classdef AOGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadCondifurationsMenu          matlab.ui.container.Menu
        SaveConfigurationsMenu          matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        DeactivatePeripheralsMenu       matlab.ui.container.Menu
        ExtraFiguresMenu                matlab.ui.container.Menu
        PMTSaturationInspector          matlab.ui.container.Menu
        ScanAssistantMenu               matlab.ui.container.Menu
        FindMyinstrumentPortMenu        matlab.ui.container.Menu
        InstrumentsMenu                 matlab.ui.container.Menu
        StagesMenu                      matlab.ui.container.Menu
        ScanningAssistantMenu           matlab.ui.container.Menu
        AFGMenu                         matlab.ui.container.Menu
        OscilloscopeMenu                matlab.ui.container.Menu
        ResetInstrumentsCommunicationMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AcoustoOpticSystemLabel         matlab.ui.control.Label
        ScanPlotTab                     matlab.ui.container.TabGroup
        PowerSpectrumAlongXTab          matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        CurrentYZPlaneTab               matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        DNavigatorTab                   matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        FFTPlotTab                      matlab.ui.container.TabGroup
        CurrentPositionFFTTab           matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        DScanTab                        matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        SimulationNamePanel             matlab.ui.container.Panel
        MeasurementNameEditFieldLabel   matlab.ui.control.Label
        MeasurementNameEditField        matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        SaveRawDataCheckBox             matlab.ui.control.CheckBox
        MeasMethod                      matlab.ui.container.TabGroup
        ScanTab                         matlab.ui.container.Tab
        RunScanButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        RealTimePlotParametersPanel     matlab.ui.container.Panel
        ChannelToPlotEditFieldLabel     matlab.ui.control.Label
        ChannelToPlotEditField          matlab.ui.control.NumericEditField
        ZCoordinateSpinnerLabel         matlab.ui.control.Label
        ZCoordinateSpinner              matlab.ui.control.Spinner
        StagesPanel                     matlab.ui.container.Panel
        ImportLimitsfromfileButton      matlab.ui.control.Button
        StagesCalibToolButton           matlab.ui.control.Button
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        PortEditFieldLabel              matlab.ui.control.Label
        PortEditField                   matlab.ui.control.EditField
        XstartpointEditField_2Label     matlab.ui.control.Label
        XstartpointEditField_2          matlab.ui.control.NumericEditField
        XendpointEditField_2Label       matlab.ui.control.Label
        XendpointEditField_2            matlab.ui.control.NumericEditField
        YstartpointEditField_2Label     matlab.ui.control.Label
        YstartpointEditField_2          matlab.ui.control.NumericEditField
        YendPointEditField_2Label       matlab.ui.control.Label
        YendPointEditField_2            matlab.ui.control.NumericEditField
        XstrideEditFieldLabel           matlab.ui.control.Label
        XstrideEditField                matlab.ui.control.NumericEditField
        YstrideEditFieldLabel           matlab.ui.control.Label
        YstrideEditField                matlab.ui.control.NumericEditField
        DNavigatorPanel                 matlab.ui.container.Panel
        rdCoordinateLabel               matlab.ui.control.Label
        rdCoordinateDropDown            matlab.ui.control.DropDown
        ImportResultsButton             matlab.ui.control.Button
        rdCoordinateIdxSpinnerLabel     matlab.ui.control.Label
        rdCoordinateIdxSpinner          matlab.ui.control.Spinner
        ConsistencyTab_2                matlab.ui.container.Tab
        RunSinglePositionButton         matlab.ui.control.Button
        StagesPanel_2                   matlab.ui.container.Panel
        mmLabel_10                      matlab.ui.control.Label
        mmLabel_11                      matlab.ui.control.Label
        XpositionEditFieldLabel         matlab.ui.control.Label
        XpositionEditField              matlab.ui.control.NumericEditField
        YpositionEditFieldLabel         matlab.ui.control.Label
        YpositionEditField              matlab.ui.control.NumericEditField
        TimeFramePropertiesPanel        matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        MaxTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MaxTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        MinTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MinTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        TimeStrideEditFieldLabel        matlab.ui.control.Label
        TimeStrideEditField             matlab.ui.control.NumericEditField
        RepetitionperTimeFrameEditFieldLabel  matlab.ui.control.Label
        RepetitionperTimeFrameEditField  matlab.ui.control.NumericEditField
        PlotParametersPanel_2           matlab.ui.container.Panel
        FrameToPlotSpinnerLabel         matlab.ui.control.Label
        FrameToPlotSpinner              matlab.ui.control.Spinner
        ZcoordinateSpinnerLabel         matlab.ui.control.Label
        ZcoordinateSpinner              matlab.ui.control.Spinner
        ImportResultsButton_2           matlab.ui.control.Button
        SettoplotSpinnerLabel           matlab.ui.control.Label
        SettoplotSpinner                matlab.ui.control.Spinner
        SingleMeasurementTab            matlab.ui.container.Tab
        SinglePositionPlotTab           matlab.ui.container.TabGroup
        TimeFrameTab                    matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        CurrFramesSetsTab               matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        CurrSetQuantTab                 matlab.ui.container.Tab
        UIAxes11                        matlab.ui.control.UIAxes
        FrameNavigatorTab               matlab.ui.container.Tab
        UIAxes12                        matlab.ui.control.UIAxes
        SetNavigatorTab                 matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        ConsistencyChTab                matlab.ui.container.Tab
        UIAxes9                         matlab.ui.control.UIAxes
        CurrFrameConsistencychTab       matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        ConsistencyTab                  matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        SystemTab                       matlab.ui.container.Tab
        SamplingFrequencyextClkPanel    matlab.ui.container.Panel
        HzLabel                         matlab.ui.control.Label
        VLabel_6                        matlab.ui.control.Label
        VLabel_7                        matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.EditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.EditField
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        ultrasoundSignalPropertiesPanel  matlab.ui.container.Panel
        MHzLabel                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        KHzLabel_3                      matlab.ui.control.Label
        SineFrequencyEditFieldLabel     matlab.ui.control.Label
        SineFrequencyEditField          matlab.ui.control.NumericEditField
        TrainFrequencyEditFieldLabel    matlab.ui.control.Label
        TrainFrequencyEditField         matlab.ui.control.NumericEditField
        SinePeriodsEditFieldLabel       matlab.ui.control.Label
        SinePeriodsEditField            matlab.ui.control.NumericEditField
        FunctionGeneratorPanel          matlab.ui.container.Panel
        SclkEditFieldLabel              matlab.ui.control.Label
        SclkEditField                   matlab.ui.control.NumericEditField
        DAQFilterDropDownLabel          matlab.ui.control.Label
        DAQFilterDropDown               matlab.ui.control.DropDown
        TriggerWidthEditFieldLabel      matlab.ui.control.Label
        TriggerWidthEditField           matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label       matlab.ui.control.Label
        AmplitudeEditField_3            matlab.ui.control.NumericEditField
        BiasEditFieldLabel              matlab.ui.control.Label
        BiasEditField                   matlab.ui.control.NumericEditField
        SsLabel                         matlab.ui.control.Label
        SLabel                          matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        DigitizerPanel                  matlab.ui.container.Panel
        Label_10                        matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        NumberOfChannelsEditFieldLabel  matlab.ui.control.Label
        NumberOfChannelsEditField       matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        PeriodtoSampleEditFieldLabel    matlab.ui.control.Label
        PeriodtoSampleEditField         matlab.ui.control.NumericEditField
        TimetosampleEditFieldLabel      matlab.ui.control.Label
        TimetosampleEditField           matlab.ui.control.NumericEditField
        VppLabel                        matlab.ui.control.Label
        VoltsRangeEditFieldLabel        matlab.ui.control.Label
        VoltsRangeEditField             matlab.ui.control.NumericEditField
        SamplingMethodDropDownLabel     matlab.ui.control.Label
        SamplingMethodDropDown          matlab.ui.control.DropDown
        LaserTab                        matlab.ui.container.Tab
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        LineWidthEditField              matlab.ui.control.NumericEditField
        SpeckleDurationEditFieldLabel   matlab.ui.control.Label
        SpeckleDurationEditField        matlab.ui.control.NumericEditField
        nmLabel                         matlab.ui.control.Label
        mWLabel                         matlab.ui.control.Label
        KHzLabel_2                      matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        UseQuantCheckBox                matlab.ui.control.CheckBox
        cmLabel                         matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        TransducerheightEditFieldLabel  matlab.ui.control.Label
        TransducerheightEditField       matlab.ui.control.NumericEditField
        PhantomdepthEditFieldLabel      matlab.ui.control.Label
        PhantomdepthEditField           matlab.ui.control.NumericEditField
        TransducerdistancefromphantomEditFieldLabel  matlab.ui.control.Label
        TransducerdistancefromphantomEditField  matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        SpeedinMediumEditFieldLabel     matlab.ui.control.Label
        SpeedinMediumEditField          matlab.ui.control.NumericEditField
        OthersTab                       matlab.ui.container.Tab
        ProblemVarsTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SystemClkTab                    matlab.ui.container.Tab
        UITable_6                       matlab.ui.control.Table
        USSignalTab                     matlab.ui.container.Tab
        UITable_5                       matlab.ui.control.Table
        SamplesTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        TimingTab                       matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        DigitizerTab_2                  matlab.ui.container.Tab
        UITable_4                       matlab.ui.control.Table
        LengthTab                       matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        FreqTab                         matlab.ui.container.Tab
        UITable_7                       matlab.ui.control.Table
    end


    properties (Access = public)
        %parameters
        importedLimits % Description
        
        %Data to plot
        scanData % full scan data
        spData   %single point
        
        %FFT plot variable
        frequencyBarShifted
        
        %ScanPlot variables
        Xplanes
        Zaxis
        Xaxis
        Yaxis
        Xidx
        
        %SinglePosition plot variable
        fBar;
        fIdx; 
        zLen;
        timeFrameVec;
        
        % Deactivate Peripherals Variables
        DontMoveStages
        DontOpenIO
        DontActivateAFG
        
        % Debug Figures
        AFGFig;
        AcquisitionFig;
        StagesFig;
        ReshapeFig;
        FFTFig;
        PostProcessingFig;
        
        handles
        
        %Plots Handles
        hFFT; % FFT display
        hFFTMarkers;% FFT matkers
        hTF; % Time Frame
        hCFC; % Current Frame Consistency
        hCFCC; % Current Frame Consistency Channel
        hCon; % Consistency (selectable Time Frame)
        hConCH; % Consistency Channel (selectable Time Frame)
        hISC; % 2D Current plane
        hPlanes; % selectable 2d plane
        hPS; % power spectrum vs Y for a given Z
        hCSet;
        hCQuant;
        hNFrames;
        hNSet;
        %titlesHandles
        hPSt;
        hFFTt;
        hPlanest;
        hISCt;
        
        % ColorBar Handle
        hCBN; % Navigator
        
        %collected user params
        userParams;
        algoValues;
        uVarAlgo;
        
        %objects
        algo
    end

    methods (Access = public)
    
        function setPositionsFromCalib(app, stages)
            app.importedLimits = stages;
        end
        
%         function updateDimensions(app, ZBar, fBar, fftIdxShort, Stages)
%             app.Zaxis = ZBar*1e3;
%             app.Xaxis = Stages.Xpos;
%             app.Yaxis = Stages.Ypos;
%             app.fBar = fBar;
%             app.fIdx = fftIdxShort;
%             app.updateZSpinnerParams()
%         end
%         
        function updateZSpinnerParams(app)
            app.ZCoordinateSpinner.Limits = [app.Zaxis(1), app.Zaxis(end)];
            app.ZCoordinateSpinner.Step = app.Zaxis(2)-app.Zaxis(1);
            if length(app.Zaxis)  > 10
                app.ZCoordinateSpinner.Value = app.Zaxis(10);
            else
                app.ZCoordinateSpinner.Value = app.Zaxis(1);
            end
                
        end
    
    
        function initScanPlots(app, ZBar, fBar, fftIdxShort, Stages)
            
            app.Zaxis = ZBar*1e3;
            app.Xaxis = Stages.Xpos;
            app.Yaxis = Stages.Ypos;
            app.fBar = fBar;
            app.fIdx = fftIdxShort;
            app.updateZSpinnerParams()

            app.scanData=[];
            app.scanData.I = zeros(Stages.Ylen, Stages.Xlen, length(ZBar));
            app.Xplanes = 0;
            app.rdCoordinateIdxSpinner.Value = 1;
            
            % FFT plot init
            channels = app.NumberOfChannelsEditField.Value;
            f = app.SineFrequencyEditField.Value*1e6;
            [app.hFFT, app.hFFTMarkers, app.hFFTt] = initFFTplot(channels, app.UIAxes, app.fBar, f);  
            
            % Current Z along X Plot
            app.hPS  = stem(app.UIAxes2, app.Yaxis, zeros(1,length(app.Yaxis)));
            app.hPSt = title(app.UIAxes2, 'Power Spectrum vs Y');
            
            % Current 2D plane Image init
            cla(app.UIAxes3, 'reset')
            app.hISC = imagesc(app.UIAxes3,...
                             'XData', app.Yaxis,...
                             'YData', app.Zaxis/1e3,...
                             'CData', zeros(length(app.Zaxis), length(app.Yaxis)));
            colorbar(app.UIAxes3)
            axis(app.UIAxes3,'fill');
            app.hPlanest = title(app.UIAxes5, ['Current Plane, X = ', num2str(app.Xaxis(1)), '[mm]']);
            
            % 2D Navigator
            app.initNavigator()
        end
        
        function initNavigator(app)
            cla(app.UIAxes5,'reset');
            limits = [min(min(min(app.scanData.I))), max(max(max(app.scanData.I)))];
            if limits(2) == limits(1); limits(2) = limits(2) + 1; end
            app.hPlanes = imagesc(app.UIAxes5,...
                             'XData', app.Yaxis,...
                             'YData', app.Zaxis,...
                             'CData', flip(permute(app.scanData.I(:,1,:), [3,1,2]),1), limits);
            axis(app.UIAxes3,'fill'); 
            app.hPlanest = title(app.UIAxes5, '2D Navigator');
            app.hCBN = colorbar(app.UIAxes5);
            app.hCBN.Limits = limits;
            
            app.rdCoordinateDropDown.Value = app.rdCoordinateDropDown.Items{1};
            app.rdCoordinateIdxSpinner.Limits = [0, length(app.Xaxis)];
            app.rdCoordinateIdxSpinner.Value = 1;
        end
    
    
        function transferScanDataAndPlot(app, I, fftRes, Xidx)
            % update measurements in app object
            app.scanData.I = I;
            app.scanData.fftRes = fftRes;
            channels = app.NumberOfChannelsEditField.Value;
            zCoor = find(app.Zaxis == app.ZCoordinateSpinner.Value);
            app.Xidx = Xidx;
                                   
            % update FFT plot
            fftMarkers = zeros(channels, 2);
            fftMarkers(:,1) = app.fBar(app.fIdx);
            fftMarkers(:,2) = permute(abs(app.scanData.fftRes(zCoor, app.fIdx, :)), [3,2,1]);
                   
            for i = 1:channels
                set(app.hFFT(i), 'XData', app.fBar, 'YData', abs(app.scanData.fftRes(zCoor, :, i)));
            end
            set(app.hFFTt, 'String', ['Curren FFT - Z = ', num2str(app.Zaxis(zCoor)), '[mm]']);
            
            for i = 1:channels 
                set(app.hFFTMarkers(i), 'XData', fftMarkers(i,1), 'YData', fftMarkers(i,2));
            end
            
            %update Power spectrum vs Y (for z)
            set(app.hPS,'Ydata',I(:,Xidx, zCoor))
            set(app.hPSt, 'String', ['Power Spectrum vs Y for (x,z) = (', num2str(app.Xaxis(Xidx)), ', ', num2str(app.Zaxis(zCoor)), ')[mm]'])
                
            % update 2D plane (current)
            set(app.hISC,  'CData', flip(permute(I(:,Xidx,:), [3,1,2]),1));
            app.hISCt = title(app.UIAxes3, ['Current Plane, X = ', num2str(app.Xaxis(Xidx)), '[mm]']);
            colorbar(app.UIAxes3);
            
            % Update Navigator Colorbar
            limits = [min(min(min(app.scanData.I))), max(max(max(app.scanData.I)))];
            if limits(2) == limits(1); limits(2) = limits(2) + 1; end
            app.hCBN = colorbar(app.UIAxes5);
            app.hCBN.Limits = limits;
        end
        
        function updateScanPlots(app)
            if isfield(app.scanData, 'I') && isfield(app.scanData, 'fftRes')
                %Fourier Transform plot - always current (frame + rep are unselectable)
                channels = app.NumberOfChannelsEditField.Value;
                zCoor = find(app.Zaxis == app.ZCoordinateSpinner.Value);
                
                fftMarkers = zeros(channels, 2);
                fftMarkers(:,1) = app.fBar(app.fIdx);
                fftMarkers(:,2) = permute(abs(app.scanData.fftRes(zCoor, app.fIdx, :)), [3,2,1]);
                
                for i = 1:channels
                    set(app.hFFT(i), 'XData', app.fBar, 'YData', abs(app.scanData.fftRes(zCoor, :, i)));
                end
                set(app.hFFTt, 'String', ['Curren FFT - Z = ', num2str(app.Zaxis(zCoor)), '[mm]']);
                
                for i = 1:channels 
                    set(app.hFFTMarkers(i), 'XData', fftMarkers(i,1), 'YData', fftMarkers(i,2));
                end
                
                %update Power spectrum vs Y (for z)
                set(app.hPS,'Ydata',app.scanData.I(:,app.Xidx, zCoor))
            end 
        end
    
        function navigator2dControl(app, value)
           if ~isempty(app.scanData.I)
                cla(app.UIAxes5, 'reset');
                limits = [min(min(min(app.scanData.I))), max(max(max(app.scanData.I)))];
                if strcmp(value,'X axis')
                    app.hPlanes = imagesc(app.UIAxes5,...
                         'XData', app.Yaxis,...
                         'YData', app.Zaxis,...
                         'CData', flip(permute(app.scanData.I(:,1,:), [3,1,2]),1),  limits);
                    app.rdCoordinateIdxSpinner.Limits = [0,  size(app.scanData.I,2)];
                    xlabel(app.UIAxes5, 'Y');
                    ylabel(app.UIAxes5, 'Z');
                    posStr = num2str(app.Xaxis(1));
                elseif strcmp(value,'Y axis')
                    app.hPlanes = imagesc(app.UIAxes5,...
                         'XData', app.Xaxis,...
                         'YData', app.Zaxis,...
                         'CData', flip(permute(app.scanData.I(1,:,:), [3,2,1]),1), limits);
                    app.rdCoordinateIdxSpinner.Limits = [0, size(app.scanData.I,1)];
                    xlabel(app.UIAxes5, 'X');
                    ylabel(app.UIAxes5, 'Z');
                    posStr = num2str(app.Yaxis(1));
                else
                    app.hPlanes = imagesc(app.UIAxes5,...
                         'XData', app.Yaxis,...
                         'YData', app.Xaxis,...
                         'CData', permute(app.scanData.I(:,:,1), [2,1,3]),  limits);
                    app.rdCoordinateIdxSpinner.Limits = [0, size(app.scanData.I,3)];
                    xlabel(app.UIAxes5, 'Y');
                    ylabel(app.UIAxes5, 'X');
                    posStr = num2str(app.Zaxis(1));
                end
                app.hPlanest = title(app.UIAxes5, ['2D Navigator, ' value(1), ' = ', posStr, '[mm]']);
                app.rdCoordinateIdxSpinner.Value = 1;
                axis (app.UIAxes5,'fill');
                app.hCBN = colorbar(app.UIAxes5);
                app.hCBN.Limits = limits;
            end             
        end
    
        function initPlotsSinglePoint(app, fBar, Timing)
            channels = app.NumberOfChannelsEditField.Value;
            f = app.SineFrequencyEditField.Value*1e6;
            maxRep = app.RepetitionperTimeFrameEditField.Value;
            
            [app.hFFT, app.hFFTMarkers] = initFFTplot(channels, app.UIAxes, fBar, f);
            
            app.hTF    = errorbar(app.UIAxes6, Timing.timeFrames, zeros(1, Timing.numOfTimeFrames), zeros(1, Timing.numOfTimeFrames));
            
%             app.hCFC   = stem(app.UIAxes8,  1:maxRep, zeros(1,maxRep));
            app.hCSet  = errorbar(app.UIAxes8,  1:maxRep, zeros(1,maxRep), zeros(1,maxRep));
            
            app.hCQuant = stem(app.UIAxes11, 1:4, zeros(1,4));
            
            app.hNFrames = errorbar(app.UIAxes12, 1:maxRep, zeros(1,maxRep), zeros(1,maxRep));
            
            app.hNSet = stem(app.UIAxes13, 1:4, zeros(1,4));

%             legendstr = cell(1,channels);
%             for i=1:channels
%                 app.hCFCC(i)  = stem(app.UIAxes10, 1:maxRep, zeros(1,maxRep));
%                 legendstr{i} = ['Ch ', num2str(i)];
%                 hold(app.UIAxes10, 'on');
%             end
%             hold(app.UIAxes10, 'off');
%             legend(app.UIAxes10, legendstr, 'Location', 'northeast')
            
%             app.hCon   = stem(app.UIAxes7,  1:maxRep, zeros(1,maxRep));
            
%             for i=1:channels
%                 app.hConCH(i) = stem(app.UIAxes9,  1:maxRep, zeros(1,maxRep));
%                 hold(app.UIAxes9, 'on');
%             end
%              hold(app.UIAxes9, 'off');
        end
  
        function transferSinglePositionDataAndPlot(app, data, curIdx)

            %%--- Transfer Data ---%%
            app.spData.phiFrame    = data.phiFrame;
            app.spData.phiFrameStd = data.phiFrameStd;
            app.spData.phiSets     = data.phiSets;
            app.spData.phiSetsStd  = data.phiSetsStd;
            app.spData.phiQuant    = data.phiQuant;
            app.spData.phiCH       = data.phiCh;
            app.spData.fftRes      = data.fftRes;
            app.spData.fIdx        = data.fftIdxShort;
            app.spData.fBar        = data.fBar;
            app.spData.zLen        = size(data.phiFrame, 1);
            app.spData.curIdx      = curIdx;
            
            %%--- Plot ---%%
            
            enableSPPlotParameters(app);
            
            frame     = app.FrameToPlotSpinner.Value;
            zCoor     = app.ZcoordinateSpinner.Value;
            channels  = app.NumberOfChannelsEditField.Value;
            
            fftMarkers      = zeros(channels, 2);
            fftMarkers(:,1) = data.fBar(data.fIdx);
            fftMarkers(:,2) = permute(abs(data.fftRes(zCoor, data.fIdx, :)), [3,2,1]);
            
            % All Frames Statistics, selectable z and repetition
            set(app.hTF, 'YData', app.spData.phiFrame(zCoor, :) ,...
                         'YNegativeDelta', app.spData.phiFrameStd(zCoor,:),...
                         'YPositiveDelta', app.spData.phiFrameStd(zCoor,:));
%             set(app.hTF, 'YData', I(zCoor, :, repToShow))
            
            % Current Frame's sets statistics
            set(app.hCSet, 'YData', app.spData.phiSets(zCoor, curIdx(1), :),...
                           'YNegativeDelta', app.spData.phiSetsStd(zCoor, curIdx(1), :),...
                           'YPositiveDelta', app.spData.phiSetsStd(zCoor, curIdx(1), :) );
%             set(app.hCFC, 'YData', I(zCoor, curTimeFramIdx, :));
            
            % Current Quanta Measurement 
            set(app.hCQuant, 'XData', 1:app.spData.numOfQuant(app.spData.curIdx(1)),...
                             'YData', app.spData.phiQuant(zCoor,...
                                                          curIdx(1),...
                                                          curIdx(2),...
                                                          1:app.spData.numOfQuant(app.spData.curIdx(1)) ));

            % Navigator - Z, frame and rep are selectable    
%             set(app.hCon, 'YData', I(zCoor, frame, :));
      

%             for i = 1:channels
%                 set(app.hCFCC(i), 'YData', V(zCoor, i, curIdx(1), :));
%             end
            
%             for i = 1:channels
%                 set(app.hConCH(i), 'YData', V(zCoor, i, frame, :));
%             end
            
            %Fourier Transform plot - always current (frame + rep are unselectable)
            for i = 1:channels
                set(app.hFFT(i), 'XData', app.spData.fBar, 'YData', abs(app.spDatafftRes(zCoor, :, i)));
            end
            
            for i = 1:channels 
                set(app.hFFTMarkers(i), 'XData', fftMarkers(i,1), 'YData', fftMarkers(i,2));
            end

        end

        function updateSinglePositionPlots(app) 
            frame     = find(app.timeFrameVec == app.FrameToPlotSpinner.Value);
            zCoor     = app.ZcoordinateSpinner.Value;
            set       = app.SettoplotSpinner.Value;
            channels  = app.NumberOfChannelsEditField.Value;
            
            fftMarkers = zeros(channels, 2);
            fftMarkers(:,1) = app.fBar(app.fIdx);
            fftMarkers(:,2) = permute(abs(app.spData.fftRes(zCoor, app.fIdx, :)), [3,2,1]);
            
            % inter frames, (selectable z)
%             set(app.hTF, 'YData', app.spData.I(zCoor, :, repToShow))
            set(app.hTF, 'YData', app.spData.phiFrame(zCoor, :) ,...
                         'YNegativeDelta', app.spData.phiFrameStd(zCoor,:),...
                         'YPositiveDelta', app.spData.phiFrameStd(zCoor,:));
                     
            % Current Frame's sets statistics (selectable z)
            set(app.hCSet, 'YData', app.spData.phiSets(zCoor, app.spData.curIdx(1), :),...
                           'YNegativeDelta', app.spData.phiSetsStd(zCoor, app.spDara.curIdx(1), :),...
                           'YPositiveDelta', app.spData.phiSetsStd(zCoor, app.spDara.curIdx(1), :) );
            
            % Current Quanta Measurement (selectable z)
            set(app.hCQuant, 'XData', 1:app.spData.numOfQuant(app.spData.curIdx(1)),...
                             'YData', app.spData.phiQuant(zCoor,...
                                                          app.spDara.curIdx(1),... 
                                                          app.spDara.curIdx(2),...
                                                          1:app.spData.numOfQuant(app.spData.curIdx(1)) ));
                         
            % Frames Navigator (Sets statistics) - selectable frame and z
            set(app.hNFrames, 'YData', app.spData.phiSets(zCoor, app.spData.curIdx(1), :),...
                              'YNegativeDelta', app.spData.phiSetsStd(zCoor, app.spDara.curIdx(1), :),...
                              'YPositiveDelta', app.spData.phiSetsStd(zCoor, app.spDara.curIdx(1), :) );
            
            % Sets Navigator (Quant Measurements) - selecable z, frame and set
            set(app.hNSet, 'XData', 1:app.spData.numOfQuant(frame),...
                           'YData', app.spData.phiQuant(zCoor, curIdx(1), curIdx(2), 1:app.spData.numOfQuant(frame)))
                           
%             for i = 1:channels
%                 set(app.hCFCC(i), 'YData', app.spData.V(zCoor, i, frame, :));
%             end
            
            % Navigator - Z, frame and rep are selectable
%             set(app.hCon, 'YData', app.spData.I(zCoor, frame, :));
            
%             for i = 1:channels
%                 set(app.hConCH(i), 'YData', app.spData.V(zCoor, i, frame, :));
%             end
            
            %Fourier Transform plot - always current (frame + rep are unselectable)
            for i = 1:channels
                set(app.hFFT(i), 'XData', app.fBar, 'YData', abs(app.spData.fftRes(zCoor, :, i)));
            end
            
            for i = 1:channels 
                set(app.hFFTMarkers(i), 'XData', fftMarkers(i,1), 'YData', fftMarkers(i,2));
            end
            
        end
        
        function updateSPSpinnersParams(app)
            app.FrameToPlotSpinner.Value = app.MinTimeFrameDurationEditField.Value;
            app.FrameToPlotSpinner.Limits = [app.MinTimeFrameDurationEditField.Value, app.MaxTimeFrameDurationEditField.Value];
            app.FrameToPlotSpinner.Step = app.TimeStrideEditField.Value;
            app.timeFrameVec = app.MinTimeFrameDurationEditField.Value: app.TimeStrideEditField.Value : app.MaxTimeFrameDurationEditField.Value;
            
            app.SettoplotSpinner.Limits = [1, app.RepetitionperTimeFrameEditField.Value];
        end
    
        function updateDebugPeripherals(app, peripherals)
            app.DontMoveStages  = peripherals.DontMoveStages;
            app.DontOpenIO      = peripherals.DontOpenIO;
            app.DontActivateAFG = peripherals.DontActivateAFG; 
        end
    
        function updateDebugFigureRequest(app, figsRequest)
            app.AFGFig = figsRequest.AFGFig;
            app.AcquisitionFig = figsRequest.AcquisitionFig;
            app.StagesFig = figsRequest.StagesFig;
            app.ReshapeFig = figsRequest.ReshapeFig;
            app.FFTFig = figsRequest.FFTFig;
            app.PostProcessingFig = figsRequest.PostProcessingFig;
        end

    end

    methods (Access = private)
    
        function applyImportedLimits(app)
             app.PortEditField.Value  = app.importedLimits.port;
             app.XstartpointEditField_2.Value = app.importedLimits.startPosX; 
             app.XendpointEditField_2.Value   = app.importedLimits.endPosX; 
             app.YstartpointEditField_2.Value = app.importedLimits.startPosY; 
             app.YendPointEditField_2.Value   = app.importedLimits.endPosY; 
             app.XstrideEditField.Value       = app.importedLimits.Xstride; 
             app.YstrideEditField.Value       = app.importedLimits.Ystride; 
        end
    
        function collectUserParams(app)
            app.userParams.Debug.dontMoveStages  = app.DontMoveStages;
            app.userParams.Debug.dontOpenIO      = app.DontOpenIO;
            app.userParams.Debug.dontActivateAFG = app.DontActivateAFG;
            
            app.userParams.Debug.AFG             = app.AFGFig;
            app.userParams.Debug.Acquisition     = app.AcquisitionFig;
            app.userParams.Debug.Stages          = app.StagesFig;
            app.userParams.Debug.Reshape         = app.ReshapeFig;
            app.userParams.Debug.FFT             = app.FFTFig;
            app.userParams.Debug.postProcessing  = app.PostProcessingFig;
            
            %Geometry
            app.userParams.Geometry.phantomHeight    = app.PhantomdepthEditField.Value/1e2;
            app.userParams.Geometry.transducerHeight = app.TransducerheightEditField.Value/1e2;
            
            % Ultrasound parameters
            app.userParams.US.freqSin       = app.SineFrequencyEditField.Value*1e6;  %[Hz]
            app.userParams.US.freqTrain     = app.TrainFrequencyEditField.Value*1e3; %[Hz]
            app.userParams.US.freqTrueTrain = 0;                                     %[Hz], init value only, true value will be recieived from AFGinit.
            app.userParams.US.speed         = app.SpeedinMediumEditField.Value;      %[m/s]
            app.userParams.US.sinPeriods    = app.SinePeriodsEditField.Value;
            
            % Function Generator Parameters
            app.userParams.AFG.freqSin       = app.userParams.US.freqSin;
            app.userParams.AFG.sinPeriods    = app.userParams.US.sinPeriods;
            app.userParams.AFG.freqTrain     = app.userParams.US.freqTrain;
            app.userParams.AFG.Sclk          = app.SclkEditField.Value;         %[Hz]
            app.userParams.AFG.triggertWidth = app.TriggerWidthEditField.Value; %[Samples]
            app.userParams.AFG.amp           = app.AmplitudeEditField_3.Value;    %[V]
            app.userParams.AFG.bias          = app.BiasEditField.Value;         %[V]
            app.userParams.AFG.daqFilter     = app.DAQFilterDropDown.Value;     %[Mhz], discrete values [25,50]
            app.userParams.AFG.extClkFreq    = app.FrequencyEditField.Value;    %[S/s] 
            app.userParams.AFG.extClkAmp     = app.AmplitudeEditField_2.Value;  %[V]
            app.userParams.AFG.extClkOff     = app.OffsetEditField.Value;       %[V]
            app.userParams.AFG.extClkDcyc    = app.DutyCycleEditField.Value;    %[%]
            app.userParams.AFG.debug         = app.userParams.Debug.AFG;
            
             % Laser Parameters
            app.userParams.Laser.waveLen = 785e-9; %[m]
            
             % Sampling Parameters
            app.userParams.Sample.freqSample            = app.FrequencyEditField.Value;           %[S/s]
            app.userParams.Sample.channels              = app.NumberOfChannelsEditField.Value;    %[#]
            app.userParams.Sample.periodsToSample       = app.PeriodtoSampleEditField.Value;      %[#]
            app.userParams.Sample.phantomHeight         = app.PhantomdepthEditField.Value/1e2;
            app.userParams.Sample.transducerHeight      = app.TransducerheightEditField.Value/1e2;
            
            % Sampling Parameters
            app.userParams.SamplingCard.freqSample      = app.userParams.Sample.freqSample;            %[S/s]
            app.userParams.SamplingCard.channels        = app.NumberOfChannelsEditField.Value;     %[#]
            app.userParams.SamplingCard.periodsToSample = app.PeriodtoSampleEditField.Value;       %[#]
            app.userParams.SamplingCard.timeToSample    = app.TimetosampleEditField.Value;         %[sec]
            app.userParams.SamplingCard.voltsRange      = app.VoltsRangeEditField.Value;           %[volts]
            app.userParams.SamplingCard.Mode.TS         = strcmp('TS', app.SamplingMethodDropDown.Value);
            app.userParams.SamplingCard.Mode.NPT        = strcmp('NPT', app.SamplingMethodDropDown.Value);
            app.userParams.SamplingCard.debug           = app.userParams.Debug.Acquisition;
           
             %General Properties
            app.userParams.General.path     = app.DirectoryEditField.Value;
            app.userParams.General.measName = app.MeasurementNameEditField.Value;
%             app.userParams.General.filename = strrep(['Results-',datestr(datetime('now'))], ':', '-');
            app.userParams.General.filename = 'app.userParamsAndTotalResults';
            app.userParams.General.useGPU   = false;
            app.userParams.General.saveRawData = app.SaveRawDataCheckBox.Value;
            
            %Plotting Properties
            app.userParams.Plotting.fftPlotHandle               = app.UIAxes;
            app.userParams.Plotting.full3DResultsHandle         = app.UIAxes4;
            app.userParams.Plotting.plotFFT                     = true;
            
            % Timing Properties
            app.userParams.Timing.speckleTime  = app.SpeckleDurationEditField.Value;
            app.userParams.Timing.useQuant     = app.UseQuantCheckBox.Value;
            
%             app.userParams = params;
        end
    
        function enableSPPlotParameters(app)
            app.FrameToPlotSpinner.Enable = true;
            app.SettoplotSpinner.Enable = true;
            app.ZcoordinateSpinner.Enable = true;
            app.ZcoordinateSpinner.Limits = [1, app.zLen];
        end
    
        function uVarAlgoPrep(app)   
           app.uVarAlgo.fSin = app.SineFrequencyEditField.Value*1e6;              
           app.uVarAlgo.fTrain = app.TrainFrequencyEditField.Value*1e3;
           app.uVarAlgo.cycInPulse = app.SinePeriodsEditField.Value;
           app.uVarAlgo.channels = app.NumberOfChannelsEditField.Value;
           
           app.uVarAlgo.c = app.SpeedinMediumEditField.Value;  
           app.uVarAlgo.phantomDepth = app.PhantomdepthEditField.Value;
           app.uVarAlgo.distFromPhantom = app.TransducerdistancefromphantomEditField.Value;
           app.uVarAlgo.bufferSizeBytes = 8*2^20;
           app.uVarAlgo.bytesPerSample = 2;
           app.uVarAlgo.preTriggerSamples = 15;
           app.uVarAlgo.fExtClk = app.FrequencyEditField.Value; %fs
           app.uVarAlgo.fSclk   = app.SclkEditField.Value;
           app.uVarAlgo.extClkDcyc = app.DutyCycleEditField.Value;
           app.uVarAlgo.useGPU = false;
           app.uVarAlgo.exportRawData = false;
           
           if app.UseQuantCheckBox.Value
               app.uVarAlgo.timeToSample = app.SpeckleDurationEditField.Value;
           else
               app.uVarAlgo.timeToSample = app.TimetosampleEditField.Value;
           end
           
        end
    
        function setAlgoValuesTable(app)
            data = app.extractStructDataToTable(app.algoValues.extClk);
            app.UITable_6.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.usSignal);
            app.UITable_5.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.samples);
            app.UITable.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.timing);
            app.UITable_3.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.digitizer);
            app.UITable_4.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.len);
            app.UITable_2.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.freq);
            app.UITable_7.Data = data;
        end
        
        function data = extractStructDataToTable(~, curStruct)
            names= fieldnames(curStruct);
            j=1;
            for i=1:numel(names)
                %this function requires mapping toolbox
                fieldVal  = extractfield(curStruct, names{i});
                if length(fieldVal)> 1
                    continue;
                end
                data{j,1} = names{i};
                data{j,2} = fieldVal;
                data{j,3} = 'unit';
                j=j+1;
            end
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            app.DontMoveStages  = true;
            app.DontOpenIO      = false;
            app.DontActivateAFG = false;
            
            app.AFGFig= false;
            app.AcquisitionFig= false;
            app.StagesFig= false;
            app.ReshapeFig= false;
            app.FFTFig= false;
            app.PostProcessingFig= false;
            
            app.FrameToPlotSpinner.Enable = false;
            app.SettoplotSpinner.Enable = false;
            app.ZcoordinateSpinner.Enable = false;
            
            app.uVarAlgoPrep();
            app.algo = Algo();
            app.algoValues = app.algo.updateAlgoUserVars(app.uVarAlgo);
            
            updateSPSpinnersParams(app)
            
        end

        % Button pushed function: RunScanButton
        function RunScanButtonPushed(app, event)
            %-------------------------------
            % Flow Parameters
            %-------------------------------
            app.collectUserParams()            
            params = app.userParams;            

            % Stages Paramteres:   
            params.Stages.port           = app.PortEditField.Value;
            params.Stages.startPosX      = app.XstartpointEditField_2.Value; %[mm]
            params.Stages.startPosY      = app.YstartpointEditField_2.Value; %[mm]
            params.Stages.endPosX        = app.XendpointEditField_2.Value; 
            params.Stages.endPosY        = app.YendPointEditField_2.Value;
            params.Stages.Xstride        = app.XstrideEditField.Value;       %[mm]
            params.Stages.Ystride        = app.YstrideEditField.Value;       %[mm]
            params.Stages.debug          = params.Debug.Stages;
            params.Stages.dontMoveStages = params.Debug.dontMoveStages;

            %Scan Plotting Properties
            params.Plotting.plotSpatialResult           = true;
            params.Plotting.plotPlanesImage             = true;
            params.Plotting.spatialPowerSpectrumHandle  = app.UIAxes2;
            params.Plotting.planeImageHandle            = app.UIAxes3;
            params.Plotting.full3DResultsHandle         = app.UIAxes4;
            params.Plotting.Zcoor                       = 10;
            params.Plotting.channelToPlot               = app.ChannelToPlotEditField.Value;
            
%             params.general.frameScan = 


            fullMeasMode(params, app)
        end

        % Button pushed function: StagesCalibToolButton
        function StagesCalibToolButtonPushed(app, event)
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app)
        end

        % Button pushed function: ImportLimitsfromfileButton
        function ImportLimitsfromfileButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.importedLimits = load([path, filename]);
            applyImportedLimits(app);
        end

        % Menu selected function: PMTSaturationInspector
        function PMTSaturationInspectorMenuSelected(app, event)
            PMTSat;
        end

        % Menu selected function: ScanAssistantMenu
        function ScanAssistantMenuSelected(app, event)
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app)
        end

        % Value changing function: rdCoordinateIdxSpinner
        function rdCoordinateIdxSpinnerValueChanging(app, event)

        end

        % Value changed function: rdCoordinateDropDown
        function rdCoordinateDropDownValueChanged(app, event)
            value = app.rdCoordinateDropDown.Value;
            app.navigator2dControl(value);
        end

        % Value changed function: rdCoordinateIdxSpinner
        function rdCoordinateIdxSpinnerValueChanged(app, event)
            value = app.rdCoordinateIdxSpinner.Value;
            if ~isempty(app.scanData.I)
                if strcmp(app.rdCoordinateDropDown.Value , 'X axis')
                    set(app.hPlanes, 'CData', flip(permute(app.scanData.I(:,value,:), [3,1,2]),1));
                    posStr = num2str(app.Xaxis(value));
                    ax = 'X';
                elseif strcmp(app.rdCoordinateDropDown.Value,'Y axis')
                    set(app.hPlanes, 'CData', flip(permute(app.scanData.I(value,:,:), [3,2,1]),1));
                    posStr = num2str(app.Yaxis(value));
                    ax = 'Y';
                else
                    set(app.hPlanes, 'CData', permute(app.scanData.I(:,:,value), [2,1,3]));
                    posStr = num2str(app.Zaxis(value));
                    ax = 'Z';
                end
                set(app.hPlanest, 'string', ['2D Navigator, ' ax, ' = ', posStr, '[mm]']);
                limits = [min(min(min(app.scanData.I))), max(max(max(app.scanData.I)))];
                if limits(2) == limits(1); limits(2) = limits(2) + 1; end
                caxis(app.UIAxes5, limits)
            end
        end

        % Value changed function: SamplingMethodDropDown
        function SamplingMethodDropDownValueChanged(app, event)
            value = app.SamplingMethodDropDown.Value;
            if strcmp(value ,'TS')
                set(app.TimetosampleEditField,'Enable','on');
                set(app.secLabel,'Enable','on');
                set(app.PeriodtoSampleEditField,'Enable','off');
                set(app.Label_2,'Enable','off');
            else
                set(app.TimetosampleEditField,'Enable','off');
                set(app.secLabel,'Enable','off');
                set(app.PeriodtoSampleEditField,'Enable','on');
                set(app.Label_2,'Enable','on');           
            end
        end

        % Button pushed function: ImportResultsButton
        function ImportResultsButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            loadedData =  load([path, filename]);
            if(~isfield(loadedData, 'I'))
               msgbox({'Invalid File'; 'Can not detect field called I'} , 'Error','error');
            elseif (~isfield(loadedData, 'params'))
                msgbox({'Invalid File'; 'Can not detect field called params'} , 'Error','error');
            else
                app.scanData.I  = loadedData.I;
                app.Zaxis = loadedData.params.Sample.Zbar;
                app.Xaxis = loadedData.params.Stages.Xpos;
                app.Yaxis = loadedData.params.Stages.Ypos;
                app.Xplanes = size(app.scanData.I,2);

                app.initNavigator();
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.DirectoryEditField.Value = uigetdir('D:\Results');
        end

        % Size changed function: ConsistencyTab_2
        function ConsistencyTab_2SizeChanged(app, event)

        end

        % Selection change function: MeasMethod
        function MeasMethodSelectionChanged(app, event)
            selectedTab = app.MeasMethod.SelectedTab;
            
            if strcmp(selectedTab.Title ,"Consistency")
                % change spatial graph accordingly
                app.SinglePositionPlotTab.Visible = true; 
                app.ScanPlotTab.Visible = false;
            else  
                % change spatial graph accordingly
                app.SinglePositionPlotTab.Visible = false; 
                app.ScanPlotTab.Visible = true;
            end
            
        end

        % Menu selected function: DeactivatePeripheralsMenu
        function DeactivatePeripheralsMenuSelected(app, event)
            currenStatus.DontMoveStages = app.DontMoveStages;
            currenStatus.DontOpenIO = app.DontOpenIO;
            currenStatus.DontActivateAFG = app.DontActivateAFG;
            peripheralDeactivate(app, currenStatus)
            
        end

        % Menu selected function: ExtraFiguresMenu
        function ExtraFiguresMenuSelected(app, event)
            currentStatus.AFGFig = app.AFGFig;
            currentStatus.AcquisitionFig = app.AcquisitionFig;
            currentStatus.StagesFig = app.StagesFig;
            currentStatus.ReshapeFig = app.ReshapeFig;
            currentStatus.FFTFig = app.FFTFig;
            currentStatus.PostProcessingFig = app.PostProcessingFig;
            
            debugFigures(app, currentStatus)
        end

        % Button pushed function: RunSinglePositionButton
        function RunSinglePositionButtonPushed(app, event)
            %-------------------------------
            % Flow Parameters
            %-------------------------------
            app.collectUserParams()
%             app.enableSPPlotParameters()
            
            params = app.userParams;            
 
            % Stages Paramteres:
            params.Stages.port           = app.PortEditField.Value;
            params.Stages.startPosX      = app.XpositionEditField.Value; %[mm]
            params.Stages.startPosY      = app.YpositionEditField.Value; %[mm]
            params.Stages.endPosX        = app.XpositionEditField.Value; 
            params.Stages.endPosY        = app.YpositionEditField.Value;
            params.Stages.Xstride        = 1;       %[mm]
            params.Stages.Ystride        = 1;       %[mm]
            params.Stages.debug          = params.Debug.Stages;
            params.Stages.dontMoveStages = params.Debug.dontMoveStages;
                         
            % Time Parameters
            params.Timing.minTimeFrameDuration = app.MinTimeFrameDurationEditField.Value;
            params.Timing.maxTimeFrameDuration = app.MaxTimeFrameDurationEditField.Value;
            params.Timing.timeStride           = app.TimeStrideEditField.Value;
            params.Timing.numOfSets      = app.RepetitionperTimeFrameEditField.Value;
            params.SamplingCard.timeToSample   = app.MaxTimeFrameDurationEditField.Value;
            
            
            % Single Position Plotting Properties
            params.Plotting.timeFrameAxes         = app.UIAxes6;
            params.Plotting.currFramConsisAxes    = app.UIAxes8;
            params.Plotting.currFrameConsisChAxes = app.UIAxes10;
            params.Plotting.consisAxes            = app.UIAxes7;
            params.Plotting.consisChAxes          = app.UIAxes9;
            
%             snrMeasMode(params, app);
              consistency(params, app);
        end

        % Value changed function: MaxTimeFrameDurationEditField
        function MaxTimeFrameDurationEditFieldValueChanged(app, event)
            app.updateSPSpinnersParams();
            
        end

        % Value changed function: MinTimeFrameDurationEditField
        function MinTimeFrameDurationEditFieldValueChanged(app, event)
            app.updateSPSpinnersParams();
            
        end

        % Value changed function: TimeStrideEditField
        function TimeStrideEditFieldValueChanged(app, event)
            app.updateSPSpinnersParams();
            
        end

        % Value changed function: RepetitionperTimeFrameEditField
        function RepetitionperTimeFrameEditFieldValueChanged(app, event)
            app.updateSPSpinnersParams();
            
        end

        % Value changed function: FrameToPlotSpinner
        function FrameToPlotSpinnerValueChanged(app, event)
            app.updateSinglePositionPlots();
            
        end

        % Value changed function: ZcoordinateSpinner
        function ZcoordinateSpinnerValueChanged(app, event)
            app.updateSinglePositionPlots();
            
        end

        % Value changed function: SettoplotSpinner
        function SettoplotSpinnerValueChanged(app, event)
            app.updateSinglePositionPlots();
            
        end

        % Value changed function: ZCoordinateSpinner
        function ZCoordinateSpinnerValueChanged(app, event)
           
           app.updateScanPlots()
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if strcmp(selectedTab.Title, 'Problem Vars')
                app.uVarAlgoPrep()
                app.algoValues = app.algo.updateAlgoUserVars(app.uVarAlgo);
                app.setAlgoValuesTable();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadCondifurationsMenu = uimenu(app.FileMenu);
            app.LoadCondifurationsMenu.Text = 'Load Condifurations';
            app.SaveConfigurationsMenu = uimenu(app.FileMenu);
            app.SaveConfigurationsMenu.Text = 'Save Configurations';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DebugMenu = uimenu(app.ToolsMenu);
            app.DebugMenu.Text = 'Debug';
            app.DeactivatePeripheralsMenu = uimenu(app.DebugMenu);
            app.DeactivatePeripheralsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeactivatePeripheralsMenuSelected, true);
            app.DeactivatePeripheralsMenu.Text = 'Deactivate Peripherals ';
            app.ExtraFiguresMenu = uimenu(app.DebugMenu);
            app.ExtraFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtraFiguresMenuSelected, true);
            app.ExtraFiguresMenu.Text = 'Extra Figures';
            app.PMTSaturationInspector = uimenu(app.ToolsMenu);
            app.PMTSaturationInspector.MenuSelectedFcn = createCallbackFcn(app, @PMTSaturationInspectorMenuSelected, true);
            app.PMTSaturationInspector.Text = 'PMT Saturation Inspector';
            app.ScanAssistantMenu = uimenu(app.ToolsMenu);
            app.ScanAssistantMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanAssistantMenuSelected, true);
            app.ScanAssistantMenu.Text = 'Scan Assistant';
            app.FindMyinstrumentPortMenu = uimenu(app.ToolsMenu);
            app.FindMyinstrumentPortMenu.Text = 'Find My instrument Port';
            app.InstrumentsMenu = uimenu(app.UIFigure);
            app.InstrumentsMenu.Text = 'Instruments';
            app.StagesMenu = uimenu(app.InstrumentsMenu);
            app.StagesMenu.Text = 'Stages';
            app.ScanningAssistantMenu = uimenu(app.StagesMenu);
            app.ScanningAssistantMenu.Text = 'Scanning Assistant';
            app.AFGMenu = uimenu(app.InstrumentsMenu);
            app.AFGMenu.Text = 'AFG';
            app.OscilloscopeMenu = uimenu(app.InstrumentsMenu);
            app.OscilloscopeMenu.Text = 'Oscilloscope';
            app.ResetInstrumentsCommunicationMenu = uimenu(app.InstrumentsMenu);
            app.ResetInstrumentsCommunicationMenu.Text = 'Reset Instruments Communication';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.AcoustoOpticSystemLabel = uilabel(app.UIFigure);
            app.AcoustoOpticSystemLabel.Text = 'AcoustoOptic System';
            app.ScanPlotTab = uitabgroup(app.UIFigure);
            app.PowerSpectrumAlongXTab = uitab(app.ScanPlotTab);
            app.PowerSpectrumAlongXTab.Title = 'Power Spectrum Along X';
            app.UIAxes2 = uiaxes(app.PowerSpectrumAlongXTab);
            title(app.UIAxes2, 'Power Spectrum Along Y ')
            xlabel(app.UIAxes2, 'Y [mm]')
            ylabel(app.UIAxes2, 'Power Spectrum')
            app.CurrentYZPlaneTab = uitab(app.ScanPlotTab);
            app.CurrentYZPlaneTab.Title = 'Current YZ Plane';
            app.UIAxes3 = uiaxes(app.CurrentYZPlaneTab);
            title(app.UIAxes3, 'Current YZ Plane')
            xlabel(app.UIAxes3, 'Y[mm]')
            ylabel(app.UIAxes3, 'Z[mm]')
            app.DNavigatorTab = uitab(app.ScanPlotTab);
            app.DNavigatorTab.Title = '2D Navigator';
            app.UIAxes5 = uiaxes(app.DNavigatorTab);
            title(app.UIAxes5, 'Planes Navigator')
            xlabel(app.UIAxes5, 'Y')
            ylabel(app.UIAxes5, 'Z')
            app.FFTPlotTab = uitabgroup(app.UIFigure);
            app.CurrentPositionFFTTab = uitab(app.FFTPlotTab);
            app.CurrentPositionFFTTab.Title = 'Current Position FFT';
            app.UIAxes = uiaxes(app.CurrentPositionFFTTab);
            title(app.UIAxes, 'Current Position FFT')
            xlabel(app.UIAxes, 'frequency [Hz]')
            ylabel(app.UIAxes, 'Power Spectrum')
            app.DScanTab = uitab(app.FFTPlotTab);
            app.DScanTab.Title = '3D Scan';
            app.UIAxes4 = uiaxes(app.DScanTab);
            title(app.UIAxes4, '3D Results')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.SimulationNamePanel = uipanel(app.UIFigure);
            app.SimulationNamePanel.Title = 'Simulation Name';
            app.MeasurementNameEditFieldLabel = uilabel(app.SimulationNamePanel);
            app.MeasurementNameEditFieldLabel.Text = 'Measurement Name';
            app.MeasurementNameEditField = uieditfield(app.SimulationNamePanel, 'text');
            app.MeasurementNameEditField.Value = 'Scan';
            app.DirectoryEditFieldLabel = uilabel(app.SimulationNamePanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.SimulationNamePanel, 'text');
            app.DirectoryEditField.Value = 'D:\Results';
            app.BrowseButton = uibutton(app.SimulationNamePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SaveRawDataCheckBox = uicheckbox(app.SimulationNamePanel);
            app.SaveRawDataCheckBox.Text = 'Save Raw Data';
            app.MeasMethod = uitabgroup(app.UIFigure);
            app.MeasMethod.SelectionChangedFcn = createCallbackFcn(app, @MeasMethodSelectionChanged, true);
            app.ScanTab = uitab(app.MeasMethod);
            app.ScanTab.Title = 'Scan';
            app.RunScanButton = uibutton(app.ScanTab, 'push');
            app.RunScanButton.ButtonPushedFcn = createCallbackFcn(app, @RunScanButtonPushed, true);
            app.RunScanButton.Text = 'Run Scan';
            app.StopButton = uibutton(app.ScanTab, 'push');
            app.StopButton.Text = 'Stop!';
            app.RealTimePlotParametersPanel = uipanel(app.ScanTab);
            app.RealTimePlotParametersPanel.Title = 'Real Time Plot Parameters';
            app.ChannelToPlotEditFieldLabel = uilabel(app.RealTimePlotParametersPanel);
            app.ChannelToPlotEditFieldLabel.Enable = 'off';
            app.ChannelToPlotEditFieldLabel.Text = 'Channel To Plot';
            app.ChannelToPlotEditField = uieditfield(app.RealTimePlotParametersPanel, 'numeric');
            app.ChannelToPlotEditField.Enable = 'off';
            app.ChannelToPlotEditField.Value = 1;
            app.ZCoordinateSpinnerLabel = uilabel(app.RealTimePlotParametersPanel);
            app.ZCoordinateSpinnerLabel.Text = 'Z Coordinate';
            app.ZCoordinateSpinner = uispinner(app.RealTimePlotParametersPanel);
            app.ZCoordinateSpinner.ValueChangedFcn = createCallbackFcn(app, @ZCoordinateSpinnerValueChanged, true);
            app.ZCoordinateSpinner.Value = 10;
            app.StagesPanel = uipanel(app.ScanTab);
            app.StagesPanel.Title = 'Stages';
            app.ImportLimitsfromfileButton = uibutton(app.StagesPanel, 'push');
            app.ImportLimitsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportLimitsfromfileButtonPushed, true);
            app.ImportLimitsfromfileButton.Text = 'Import Limits from file';
            app.StagesCalibToolButton = uibutton(app.StagesPanel, 'push');
            app.StagesCalibToolButton.ButtonPushedFcn = createCallbackFcn(app, @StagesCalibToolButtonPushed, true);
            app.StagesCalibToolButton.Text = 'Stages Calib Tool';
            app.mmLabel_4 = uilabel(app.StagesPanel);
            app.mmLabel_4.Text = '[mm]';
            app.mmLabel_5 = uilabel(app.StagesPanel);
            app.mmLabel_5.Text = '[mm]';
            app.mmLabel_6 = uilabel(app.StagesPanel);
            app.mmLabel_6.Text = '[mm]';
            app.mmLabel_7 = uilabel(app.StagesPanel);
            app.mmLabel_7.Text = '[mm]';
            app.mmLabel_8 = uilabel(app.StagesPanel);
            app.mmLabel_8.Text = '[mm]';
            app.mmLabel_9 = uilabel(app.StagesPanel);
            app.mmLabel_9.Text = '[mm]';
            app.PortEditFieldLabel = uilabel(app.StagesPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.StagesPanel, 'text');
            app.PortEditField.Value = 'COM3';
            app.XstartpointEditField_2Label = uilabel(app.StagesPanel);
            app.XstartpointEditField_2Label.Text = 'X start point';
            app.XstartpointEditField_2 = uieditfield(app.StagesPanel, 'numeric');
            app.XstartpointEditField_2.Value = 129;
            app.XendpointEditField_2Label = uilabel(app.StagesPanel);
            app.XendpointEditField_2Label.Text = 'X end point';
            app.XendpointEditField_2 = uieditfield(app.StagesPanel, 'numeric');
            app.XendpointEditField_2.Value = 119;
            app.YstartpointEditField_2Label = uilabel(app.StagesPanel);
            app.YstartpointEditField_2Label.Text = 'Y start point';
            app.YstartpointEditField_2 = uieditfield(app.StagesPanel, 'numeric');
            app.YstartpointEditField_2.Value = 70;
            app.YendPointEditField_2Label = uilabel(app.StagesPanel);
            app.YendPointEditField_2Label.Text = 'Y end Point';
            app.YendPointEditField_2 = uieditfield(app.StagesPanel, 'numeric');
            app.YendPointEditField_2.Value = 100;
            app.XstrideEditFieldLabel = uilabel(app.StagesPanel);
            app.XstrideEditFieldLabel.Text = 'X stride';
            app.XstrideEditField = uieditfield(app.StagesPanel, 'numeric');
            app.XstrideEditField.Value = -0.5;
            app.YstrideEditFieldLabel = uilabel(app.StagesPanel);
            app.YstrideEditFieldLabel.Text = 'Y stride';
            app.YstrideEditField = uieditfield(app.StagesPanel, 'numeric');
            app.YstrideEditField.Value = 0.5;
            app.DNavigatorPanel = uipanel(app.ScanTab);
            app.DNavigatorPanel.Title = '2D Navigator';
            app.rdCoordinateLabel = uilabel(app.DNavigatorPanel);
            app.rdCoordinateLabel.Text = ' 3rd Coordinate';
            app.rdCoordinateDropDown = uidropdown(app.DNavigatorPanel);
            app.rdCoordinateDropDown.Items = {'X axis', 'Y axis', 'Z axis'};
            app.rdCoordinateDropDown.ValueChangedFcn = createCallbackFcn(app, @rdCoordinateDropDownValueChanged, true);
            app.rdCoordinateDropDown.Value = 'Z axis';
            app.ImportResultsButton = uibutton(app.DNavigatorPanel, 'push');
            app.ImportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportResultsButtonPushed, true);
            app.ImportResultsButton.Text = 'Import Results';
            app.rdCoordinateIdxSpinnerLabel = uilabel(app.ScanTab);
            app.rdCoordinateIdxSpinnerLabel.Text = '3rd Coordinate Idx';
            app.rdCoordinateIdxSpinner = uispinner(app.ScanTab);
            app.rdCoordinateIdxSpinner.LowerLimitInclusive = 'off';
            app.rdCoordinateIdxSpinner.ValueChangingFcn = createCallbackFcn(app, @rdCoordinateIdxSpinnerValueChanging, true);
            app.rdCoordinateIdxSpinner.Limits = [0 Inf];
            app.rdCoordinateIdxSpinner.RoundFractionalValues = 'on';
            app.rdCoordinateIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @rdCoordinateIdxSpinnerValueChanged, true);
            app.rdCoordinateIdxSpinner.Value = 1;
            app.ConsistencyTab_2 = uitab(app.MeasMethod);
            app.ConsistencyTab_2.SizeChangedFcn = createCallbackFcn(app, @ConsistencyTab_2SizeChanged, true);
            app.ConsistencyTab_2.Title = 'Consistency';
            app.RunSinglePositionButton = uibutton(app.ConsistencyTab_2, 'push');
            app.RunSinglePositionButton.ButtonPushedFcn = createCallbackFcn(app, @RunSinglePositionButtonPushed, true);
            app.RunSinglePositionButton.Text = 'Run Single Position';
            app.StagesPanel_2 = uipanel(app.ConsistencyTab_2);
            app.StagesPanel_2.Title = 'Stages';
            app.mmLabel_10 = uilabel(app.StagesPanel_2);
            app.mmLabel_10.Text = '[mm]';
            app.mmLabel_11 = uilabel(app.StagesPanel_2);
            app.mmLabel_11.Text = '[mm]';
            app.XpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.XpositionEditFieldLabel.Text = 'X position';
            app.XpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.XpositionEditField.Value = 129;
            app.YpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.YpositionEditFieldLabel.Text = 'Y position';
            app.YpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.YpositionEditField.Value = 82;
            app.TimeFramePropertiesPanel = uipanel(app.ConsistencyTab_2);
            app.TimeFramePropertiesPanel.Title = 'Time Frame Properties';
            app.sLabel = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel.Text = '[s]';
            app.sLabel_2 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_2.Text = '[s]';
            app.sLabel_3 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_3.Text = '[s]';
            app.sLabel_4 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_4.Text = '[s]';
            app.MaxTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MaxTimeFrameDurationEditFieldLabel.Text = 'Max Time Frame Duration';
            app.MaxTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MaxTimeFrameDurationEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeFrameDurationEditFieldValueChanged, true);
            app.MaxTimeFrameDurationEditField.Value = 20;
            app.MinTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MinTimeFrameDurationEditFieldLabel.Text = 'Min Time Frame Duration';
            app.MinTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MinTimeFrameDurationEditField.ValueChangedFcn = createCallbackFcn(app, @MinTimeFrameDurationEditFieldValueChanged, true);
            app.MinTimeFrameDurationEditField.Value = 2;
            app.TimeStrideEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.TimeStrideEditFieldLabel.Text = 'Time Stride';
            app.TimeStrideEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.TimeStrideEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStrideEditFieldValueChanged, true);
            app.TimeStrideEditField.Value = 2;
            app.RepetitionperTimeFrameEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.RepetitionperTimeFrameEditFieldLabel.Text = 'Repetition per Time Frame';
            app.RepetitionperTimeFrameEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.RepetitionperTimeFrameEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitionperTimeFrameEditFieldValueChanged, true);
            app.RepetitionperTimeFrameEditField.Value = 10;
            app.PlotParametersPanel_2 = uipanel(app.ConsistencyTab_2);
            app.PlotParametersPanel_2.Title = 'Plot Parameters';
            app.FrameToPlotSpinnerLabel = uilabel(app.PlotParametersPanel_2);
            app.FrameToPlotSpinnerLabel.Text = 'Frame To Plot';
            app.FrameToPlotSpinner = uispinner(app.PlotParametersPanel_2);
            app.FrameToPlotSpinner.Limits = [1 2];
            app.FrameToPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameToPlotSpinnerValueChanged, true);
            app.FrameToPlotSpinner.Value = 1;
            app.ZcoordinateSpinnerLabel = uilabel(app.PlotParametersPanel_2);
            app.ZcoordinateSpinnerLabel.Text = 'Z coordinate';
            app.ZcoordinateSpinner = uispinner(app.PlotParametersPanel_2);
            app.ZcoordinateSpinner.Limits = [1 17];
            app.ZcoordinateSpinner.ValueChangedFcn = createCallbackFcn(app, @ZcoordinateSpinnerValueChanged, true);
            app.ZcoordinateSpinner.Value = 10;
            app.ImportResultsButton_2 = uibutton(app.PlotParametersPanel_2, 'push');
            app.ImportResultsButton_2.Enable = 'off';
            app.ImportResultsButton_2.Text = 'Import Results';
            app.SettoplotSpinnerLabel = uilabel(app.PlotParametersPanel_2);
            app.SettoplotSpinnerLabel.Text = 'Set to plot';
            app.SettoplotSpinner = uispinner(app.PlotParametersPanel_2);
            app.SettoplotSpinner.Limits = [1 2];
            app.SettoplotSpinner.ValueChangedFcn = createCallbackFcn(app, @SettoplotSpinnerValueChanged, true);
            app.SettoplotSpinner.Value = 1;
            app.SingleMeasurementTab = uitab(app.MeasMethod);
            app.SingleMeasurementTab.Title = 'Single Measurement';
            app.SinglePositionPlotTab = uitabgroup(app.UIFigure);
            app.SinglePositionPlotTab.Visible = 'off';
            app.TimeFrameTab = uitab(app.SinglePositionPlotTab);
            app.TimeFrameTab.Title = 'Time Frame ';
            app.UIAxes6 = uiaxes(app.TimeFrameTab);
            title(app.UIAxes6, 'Time Frame Length vs. Intensity')
            xlabel(app.UIAxes6, 'Time Frame Length [s]')
            ylabel(app.UIAxes6, 'Intensity')
            app.CurrFramesSetsTab = uitab(app.SinglePositionPlotTab);
            app.CurrFramesSetsTab.Title = 'Curr. Frame''s Sets';
            app.UIAxes8 = uiaxes(app.CurrFramesSetsTab);
            title(app.UIAxes8, 'Current Frame''s Set')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.CurrSetQuantTab = uitab(app.SinglePositionPlotTab);
            app.CurrSetQuantTab.Title = 'Curr. Set Quant';
            app.UIAxes11 = uiaxes(app.CurrSetQuantTab);
            title(app.UIAxes11, 'Current Set Qunata')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            app.FrameNavigatorTab = uitab(app.SinglePositionPlotTab);
            app.FrameNavigatorTab.Title = 'Frame Navigator';
            app.UIAxes12 = uiaxes(app.FrameNavigatorTab);
            title(app.UIAxes12, 'Frame Navigator')
            xlabel(app.UIAxes12, 'Set Number')
            ylabel(app.UIAxes12, 'Fluence Rate')
            app.SetNavigatorTab = uitab(app.SinglePositionPlotTab);
            app.SetNavigatorTab.Title = 'Set Navigator';
            app.UIAxes13 = uiaxes(app.SetNavigatorTab);
            title(app.UIAxes13, 'Set Navigator')
            xlabel(app.UIAxes13, 'Set Instance')
            ylabel(app.UIAxes13, 'Y')
            app.ConsistencyChTab = uitab(app.SinglePositionPlotTab);
            app.ConsistencyChTab.Title = 'Consistency (Ch)';
            app.UIAxes9 = uiaxes(app.ConsistencyChTab);
            title(app.UIAxes9, 'Time Frame Consistency (Averaged Value)')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.CurrFrameConsistencychTab = uitab(app.SinglePositionPlotTab);
            app.CurrFrameConsistencychTab.Title = 'Curr. Frame Consistency (ch)';
            app.UIAxes10 = uiaxes(app.CurrFrameConsistencychTab);
            title(app.UIAxes10, 'Current Frame Consistency (Value Per Channel)')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            app.ConsistencyTab = uitab(app.SinglePositionPlotTab);
            app.ConsistencyTab.Title = 'Consistency';
            app.UIAxes7 = uiaxes(app.ConsistencyTab);
            title(app.UIAxes7, 'Time Frame Consistency (Averaged Value)')
            xlabel(app.UIAxes7, 'Time Frame Index')
            ylabel(app.UIAxes7, 'Power spectrum (Intensity)')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.SamplingFrequencyextClkPanel = uipanel(app.SystemTab);
            app.SamplingFrequencyextClkPanel.Title = 'Sampling Frequency (extClk)';
            app.HzLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.HzLabel.Text = '[Hz]';
            app.VLabel_6 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_6.Text = '[V]';
            app.VLabel_7 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_7.Text = '[V]';
            app.Label_8 = uilabel(app.SamplingFrequencyextClkPanel);
            app.Label_8.Text = '[%]';
            app.FrequencyEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.FrequencyEditField.Value = 5000000;
            app.AmplitudeEditField_2Label = uilabel(app.SamplingFrequencyextClkPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.AmplitudeEditField_2.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.OffsetEditField.Value = '0';
            app.DutyCycleEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.DutyCycleEditField.Value = 50;
            app.ultrasoundSignalPropertiesPanel = uipanel(app.SystemTab);
            app.ultrasoundSignalPropertiesPanel.Title = 'ultrasound Signal Properties';
            app.MHzLabel = uilabel(app.ultrasoundSignalPropertiesPanel);
            app.MHzLabel.Text = '[MHz]';
            app.Label_9 = uilabel(app.ultrasoundSignalPropertiesPanel);
            app.Label_9.Text = '[#]';
            app.KHzLabel_3 = uilabel(app.ultrasoundSignalPropertiesPanel);
            app.KHzLabel_3.Text = '[KHz]';
            app.SineFrequencyEditFieldLabel = uilabel(app.ultrasoundSignalPropertiesPanel);
            app.SineFrequencyEditFieldLabel.Text = 'Sine Frequency';
            app.SineFrequencyEditField = uieditfield(app.ultrasoundSignalPropertiesPanel, 'numeric');
            app.SineFrequencyEditField.Value = 1.25;
            app.TrainFrequencyEditFieldLabel = uilabel(app.ultrasoundSignalPropertiesPanel);
            app.TrainFrequencyEditFieldLabel.Text = 'Train Frequency';
            app.TrainFrequencyEditField = uieditfield(app.ultrasoundSignalPropertiesPanel, 'numeric');
            app.TrainFrequencyEditField.Value = 19;
            app.SinePeriodsEditFieldLabel = uilabel(app.ultrasoundSignalPropertiesPanel);
            app.SinePeriodsEditFieldLabel.Text = 'Sine Periods';
            app.SinePeriodsEditField = uieditfield(app.ultrasoundSignalPropertiesPanel, 'numeric');
            app.SinePeriodsEditField.Value = 4;
            app.FunctionGeneratorPanel = uipanel(app.SystemTab);
            app.FunctionGeneratorPanel.Title = 'Function Generator';
            app.SclkEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.SclkEditFieldLabel.Text = 'Sclk';
            app.SclkEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.SclkEditField.Value = 100000000;
            app.DAQFilterDropDownLabel = uilabel(app.FunctionGeneratorPanel);
            app.DAQFilterDropDownLabel.Text = 'DAQ Filter';
            app.DAQFilterDropDown = uidropdown(app.FunctionGeneratorPanel);
            app.DAQFilterDropDown.Items = {'25MHz', '50MHz'};
            app.DAQFilterDropDown.Value = '50MHz';
            app.TriggerWidthEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.TriggerWidthEditFieldLabel.Text = 'Trigger Width';
            app.TriggerWidthEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.TriggerWidthEditField.Value = 500;
            app.AmplitudeEditField_3Label = uilabel(app.FunctionGeneratorPanel);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.AmplitudeEditField_3.Value = 1;
            app.BiasEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.BiasEditFieldLabel.Text = 'Bias';
            app.BiasEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.SsLabel = uilabel(app.FunctionGeneratorPanel);
            app.SsLabel.Text = '[S/s]';
            app.SLabel = uilabel(app.FunctionGeneratorPanel);
            app.SLabel.Text = '[S]';
            app.VLabel_8 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_8.Text = '[V]';
            app.VLabel_9 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_9.Text = '[V]';
            app.DigitizerPanel = uipanel(app.SystemTab);
            app.DigitizerPanel.Title = 'Digitizer';
            app.Label_10 = uilabel(app.DigitizerPanel);
            app.Label_10.Text = '[#]';
            app.secLabel = uilabel(app.DigitizerPanel);
            app.secLabel.Text = '[sec]';
            app.NumberOfChannelsEditFieldLabel = uilabel(app.DigitizerPanel);
            app.NumberOfChannelsEditFieldLabel.Text = 'Number Of Channels';
            app.NumberOfChannelsEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.NumberOfChannelsEditField.Value = 4;
            app.Label_11 = uilabel(app.DigitizerPanel);
            app.Label_11.Enable = 'off';
            app.Label_11.Text = '[#]';
            app.PeriodtoSampleEditFieldLabel = uilabel(app.DigitizerPanel);
            app.PeriodtoSampleEditFieldLabel.Text = 'Period to Sample';
            app.PeriodtoSampleEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.PeriodtoSampleEditField.Enable = 'off';
            app.PeriodtoSampleEditField.Value = 8192;
            app.TimetosampleEditFieldLabel = uilabel(app.DigitizerPanel);
            app.TimetosampleEditFieldLabel.Text = 'Time to sample';
            app.TimetosampleEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.TimetosampleEditField.Value = 2;
            app.VppLabel = uilabel(app.DigitizerPanel);
            app.VppLabel.Text = '[Vpp]';
            app.VoltsRangeEditFieldLabel = uilabel(app.DigitizerPanel);
            app.VoltsRangeEditFieldLabel.Text = 'Volts Range';
            app.VoltsRangeEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.VoltsRangeEditField.Value = 2;
            app.SamplingMethodDropDownLabel = uilabel(app.DigitizerPanel);
            app.SamplingMethodDropDownLabel.Text = 'Sampling Method';
            app.SamplingMethodDropDown = uidropdown(app.DigitizerPanel);
            app.SamplingMethodDropDown.Items = {'NPT', 'TS'};
            app.SamplingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingMethodDropDownValueChanged, true);
            app.SamplingMethodDropDown.Value = 'TS';
            app.LaserTab = uitab(app.TabGroup);
            app.LaserTab.Title = 'Laser';
            app.WavelengthEditFieldLabel = uilabel(app.LaserTab);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.LaserTab, 'numeric');
            app.WavelengthEditField.Value = 785;
            app.PowerEditFieldLabel = uilabel(app.LaserTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.LaserTab, 'numeric');
            app.PowerEditField.Value = 50;
            app.LineWidthEditFieldLabel = uilabel(app.LaserTab);
            app.LineWidthEditFieldLabel.Text = 'LineWidth';
            app.LineWidthEditField = uieditfield(app.LaserTab, 'numeric');
            app.LineWidthEditField.Value = 5000;
            app.SpeckleDurationEditFieldLabel = uilabel(app.LaserTab);
            app.SpeckleDurationEditFieldLabel.Text = 'Speckle Duration';
            app.SpeckleDurationEditField = uieditfield(app.LaserTab, 'numeric');
            app.SpeckleDurationEditField.Value = 0.5;
            app.nmLabel = uilabel(app.LaserTab);
            app.nmLabel.Text = '[nm]';
            app.mWLabel = uilabel(app.LaserTab);
            app.mWLabel.Text = '[mW]';
            app.KHzLabel_2 = uilabel(app.LaserTab);
            app.KHzLabel_2.Text = '[KHz]';
            app.sLabel_5 = uilabel(app.LaserTab);
            app.sLabel_5.Text = '[s]';
            app.UseQuantCheckBox = uicheckbox(app.LaserTab);
            app.UseQuantCheckBox.Text = 'Use Quant';
            app.cmLabel = uilabel(app.LaserTab);
            app.cmLabel.Text = '[cm]';
            app.cmLabel_2 = uilabel(app.LaserTab);
            app.cmLabel_2.Text = '[cm]';
            app.cmLabel_3 = uilabel(app.LaserTab);
            app.cmLabel_3.Text = '[cm]';
            app.TransducerheightEditFieldLabel = uilabel(app.LaserTab);
            app.TransducerheightEditFieldLabel.Text = 'Transducer height';
            app.TransducerheightEditField = uieditfield(app.LaserTab, 'numeric');
            app.TransducerheightEditField.Value = 11.2;
            app.PhantomdepthEditFieldLabel = uilabel(app.LaserTab);
            app.PhantomdepthEditFieldLabel.Text = 'Phantom depth';
            app.PhantomdepthEditField = uieditfield(app.LaserTab, 'numeric');
            app.PhantomdepthEditField.Value = 4.6;
            app.TransducerdistancefromphantomEditFieldLabel = uilabel(app.LaserTab);
            app.TransducerdistancefromphantomEditFieldLabel.Text = {'Transducer distance'; ' from phantom'};
            app.TransducerdistancefromphantomEditField = uieditfield(app.LaserTab, 'numeric');
            app.TransducerdistancefromphantomEditField.Value = 6.6;
            app.msLabel = uilabel(app.LaserTab);
            app.msLabel.Text = '[m/s]';
            app.SpeedinMediumEditFieldLabel = uilabel(app.LaserTab);
            app.SpeedinMediumEditFieldLabel.Text = 'Speed in Medium';
            app.SpeedinMediumEditField = uieditfield(app.LaserTab, 'numeric');
            app.SpeedinMediumEditField.Value = 1550;
            app.OthersTab = uitab(app.TabGroup);
            app.OthersTab.Title = 'Others';
            app.ProblemVarsTab = uitab(app.TabGroup);
            app.ProblemVarsTab.Title = 'Problem Vars';
            app.TabGroup2 = uitabgroup(app.ProblemVarsTab);
            app.SystemClkTab = uitab(app.TabGroup2);
            app.SystemClkTab.Title = 'System Clk';
            app.UITable_6 = uitable(app.SystemClkTab);
            app.UITable_6.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_6.RowName = {};
            app.UITable_6.ColumnEditable = false;
            app.USSignalTab = uitab(app.TabGroup2);
            app.USSignalTab.Title = 'US Signal';
            app.UITable_5 = uitable(app.USSignalTab);
            app.UITable_5.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_5.RowName = {};
            app.UITable_5.ColumnEditable = false;
            app.SamplesTab = uitab(app.TabGroup2);
            app.SamplesTab.Title = 'Samples';
            app.UITable = uitable(app.SamplesTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = false;
            app.TimingTab = uitab(app.TabGroup2);
            app.TimingTab.Title = 'Timing';
            app.UITable_3 = uitable(app.TimingTab);
            app.UITable_3.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = false;
            app.DigitizerTab_2 = uitab(app.TabGroup2);
            app.DigitizerTab_2.Title = 'Digitizer';
            app.UITable_4 = uitable(app.DigitizerTab_2);
            app.UITable_4.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = false;
            app.LengthTab = uitab(app.TabGroup2);
            app.LengthTab.Title = 'Length';
            app.UITable_2 = uitable(app.LengthTab);
            app.UITable_2.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = false;
            app.FreqTab = uitab(app.TabGroup2);
            app.FreqTab.Title = 'Freq';
            app.UITable_7 = uitable(app.FreqTab);
            app.UITable_7.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_7.RowName = {};
            app.UITable_7.ColumnEditable = false;
        end
    end

    methods (Access = public)

        % Construct app
        function app = AOGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end